classdef filtering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiltrelemeUIFigure     matlab.ui.Figure
        PREVButton             matlab.ui.control.Button
        NEXTButton             matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
        FiltrelemeButtonGroup  matlab.ui.container.ButtonGroup
        FiltrelemeUygulamakIstemiyorumButton  matlab.ui.control.RadioButton
        FiltrelemeUygulamakIstiyorumButton  matlab.ui.control.RadioButton
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallerApp % Description
        img % Description
        proc_img % Description
        morfolojik % Description
        preProc2 % Description
        gr_img % Description
    end
    
    methods (Access = private)
        
        
        function filtr = konvolisyon(app,img,mask)
            [h,w]=size(img);
            [m,n]=size(mask);
            yeni=zeros(h,w);
            pad=round((m-1)/2);
            img=padarray(img,[pad pad],0,'both');
            for i=1:h
                for j=1:w
                    temp=imcrop(img,[j,i,m-1,n-1]);
                    %disp(i+","+j+" tmep"+temp);
                    yeni(i,j)=app.carp(temp,mask);
                end
            end
            %disp(yeni)
            filtr=(yeni);
            %imshow(filtr,"Parent",app.UIAxes)
        end
        
        function carpim = carp(app,temp,mask)
            %disp("temp: "+temp)
            %disp("mask: "+mask)
            [a,b]=size(mask);
            sum=0;
            for i=1:a
                for j=1:b
                    sum=sum+(temp(i,j)*mask(i,j));
                end
            end
            %disp("carpim: "+sum)
            carpim=sum;
        end
        
        function gauss_mask = gauss_mask(app,sigma,n)
            a=-round((n-1)/2);
            g_mask=zeros(n,n);
            tam=0;
            for i=1:n
                b=-round((n-1)/2);
                for j=1:n
                    kok=1/(sqrt(2*pi)*power(sigma,2));
                    e_ussu=exp(-(power(a,2)+power(b,2))/2*power(sigma,2));
                    carp=kok*e_ussu;
                    if tam==0
                        tam=1/carp;
                    end
                    %disp("a:"+a+" b:"+b+" kok:"+kok+" e ussu:"+e_ussu+" carpim:"+kok*e_ussu)
                    g_mask(i,j)=round(carp*tam);
                    b=b+1;
                end
                a=a+1;
            end
            gauss_mask=g_mask;
            disp(gauss_mask)
        end
        
        function ort_img = ortanca(app,img,n)
            [h,w]=size(img);
            a=h-(n-1); b=w-(n-1);
            yeni=zeros(a,b);
            for i=1:a
                for j=1:b
                    temp=imcrop(img,[j,i,n-1,n-1]);
                    temp=temp';
                    temp=temp(:)';
                    temp=sort(temp);
                    yeni(i,j)=median(temp);
                end
            end
            ort_img=mat2gray(yeni);
        end
        
        function kh_img = kontra_harmonik(app,data,Q,n)
            data=im2double(data);
            masksize=n;
            sumn=[];
            sumd=[];
            pixln=0;
            pixld=0;
            [ro col]=size(data);
            for i=1:ro;
                for j=1:col;
                    for m=-masksize:masksize;
                        for n=-masksize:masksize;
                            if (i+m>0 && i+m<ro && j+n>0 && j+n<col && ...      
                                    masksize+m>0 && masksize+m<ro && ...
                                    masksize+n>0 && masksize+n<col)

                                pixl1=(data(i+m,j+n)).^(Q+1);                   
                                pixl2= (data(i+m,j+n)).^Q;
                                pixln=pixln+pixl1;                                  
                                pixld=pixld+pixl2;

                            end
                        end
                    end
                    reformedimage(i,j)=(pixln/pixld);
                    pixln=0;
                    pixld=0;
                end
            end
            kh_img=mat2gray(reformedimage);
        end
        
        function toplam = topla(app,img1,img2)
            [h,w]=size(img1);
            top=zeros(h,w);
            for i=1:h
                for j=1:w
                    top(i,j)=img1(i,j)+img2(i,j);
                     disp(img1(i,j)+"+"+img2(i,j)+"="+top(i,j))
                end
            end
            toplam=mat2gray(top);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, img)
            app.CallerApp=caller;
            app.img=img;
            app.proc_img=img;
        end

        % Selection changed function: FiltrelemeButtonGroup
        function FiltrelemeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FiltrelemeButtonGroup.SelectedObject;
            if isequal(selectedButton.Text,'Filtreleme Uygulamak Istiyorum')
                app.DropDown.Visible="on";
                app.UIAxes.Visible="on";
                [h,w,c]=size(app.img);
                if c==3
                    app.gr_img=rgb2gray(app.img);
                else
                    app.gr_img=app.img;
                end
                imshow(app.img,"Parent",app.UIAxes);
            else
                app.DropDown.Visible="off";
                app.UIAxes.Visible="off";
            end    
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if isequal(value,"Gaussian Bulanıklaştırma filtresi(Parametre olarak istenilen standart sapma (σ) değerine göre)")
                sigma=1;
                % 5x5 lik gauss maskesini sigmaya gore olusturacak
                gauss_mask=app.gauss_mask(sigma,3);
                gauss_mask=gauss_mask/sum(gauss_mask,"all");
                app.FiltrelemeUIFigure.Name='Filtreleme devam ediyor';
                filtr_img=app.konvolisyon(app.gr_img,gauss_mask);
                filtr_img=mat2gray(filtr_img);
                app.FiltrelemeUIFigure.Name='Filtreleme';
                imshow(filtr_img,"Parent",app.UIAxes);
                
            elseif isequal(value, "Keskinleştirme filtresi")
                mask=[0 1 0;
                      1 -4 1;
                      0 1 0];
                app.FiltrelemeUIFigure.Name='Filtreleme devam ediyor';
                blur_img=app.konvolisyon(app.gr_img,mask);
                blur_img=mat2gray(blur_img);
                %filtr_img=imfuse(app.gr_img,blur_img);
                filtr_img=app.topla(app.gr_img,blur_img);
                app.FiltrelemeUIFigure.Name='Filtreleme';
                imshow(filtr_img,"Parent",app.UIAxes);
                
%                 h=fspecial("log");
%                 keskin=imfilter(app.gr_img,h);
%                 imshow(keskin,"Parent",app.UIAxes);
%                 filtr_img=app.topla(app.gr_img,keskin);
%                 imshow(filtr_img);
            elseif isequal(value, "Kenar bulma filtresi")
                maskx=[1 1 1;
                       0 0 0;
                       -1 -1 -1];
                masky=[1 0 -1;
                       1 0 -1;
                       1 0 -1];
                app.FiltrelemeUIFigure.Name='Filtreleme devam ediyor';
                kenarx=app.konvolisyon(app.gr_img,maskx);
                kenary=app.konvolisyon(app.gr_img,masky);
                filtr_img=app.topla(kenary,kenarx);
                %filtr_img=imfuse(kenary,kenarx);
                app.FiltrelemeUIFigure.Name='Filtreleme';
                imshow(filtr_img,"Parent",app.UIAxes);
            elseif isequal(value, "Ortalama (Mean) filtresi")
                mask=ones(5,5);
                mask=mask/sum(mask,"all");
                app.FiltrelemeUIFigure.Name='Filtreleme devam ediyor';
                filtr_img=app.konvolisyon(app.gr_img,mask);
                filtr_img=mat2gray(filtr_img);
                app.FiltrelemeUIFigure.Name='Filtreleme';
                imshow(filtr_img,"Parent",app.UIAxes);
            elseif isequal(value, "Ortanca (Median) filtresi")
                app.FiltrelemeUIFigure.Name='Filtreleme devam ediyor';
                filtr_img=app.ortanca(app.gr_img,3);
                app.FiltrelemeUIFigure.Name='Filtreleme';
                imshow(filtr_img,"Parent",app.UIAxes);
            elseif isequal(value, "Kontra Harmonik Ortalama filtresi")
                Q=1;
                app.FiltrelemeUIFigure.Name='Filtreleme devam ediyor';
                filtr_img=app.kontra_harmonik(app.gr_img,Q,3);
                app.FiltrelemeUIFigure.Name='Filtreleme';
                imshow(filtr_img,"Parent",app.UIAxes);
            end
            app.proc_img=filtr_img;
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.morfolojik=morfolojik(app,app.proc_img);
            app.delete;
        end

        % Button pushed function: PREVButton
        function PREVButtonPushed(app, event)
            app.preProc2=preProcessing2(app,app.img);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiltrelemeUIFigure = uifigure('Visible', 'off');
            app.FiltrelemeUIFigure.Name = 'Filtreleme';
            app.UIAxes = uiaxes(app.FiltrelemeUIFigure);
            title(app.UIAxes, 'Filtreleme Sonucu')
            app.UIAxes.Visible = 'off';
            app.FiltrelemeButtonGroup = uibuttongroup(app.FiltrelemeUIFigure);
            app.FiltrelemeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltrelemeButtonGroupSelectionChanged, true);
            app.FiltrelemeButtonGroup.Title = 'Filtreleme ';
            app.FiltrelemeUygulamakIstiyorumButton = uiradiobutton(app.FiltrelemeButtonGroup);
            app.FiltrelemeUygulamakIstiyorumButton.Text = 'Filtreleme Uygulamak Istiyorum';
            app.FiltrelemeUygulamakIstemiyorumButton = uiradiobutton(app.FiltrelemeButtonGroup);
            app.FiltrelemeUygulamakIstemiyorumButton.Text = 'Filtreleme  Uygulamak Istemiyorum';
            app.FiltrelemeUygulamakIstemiyorumButton.Value = true;
            app.DropDown = uidropdown(app.FiltrelemeUIFigure);
            app.DropDown.Items = {'Filtreleme Menüsü', 'Gaussian Bulanıklaştırma filtresi(Parametre olarak istenilen standart sapma (σ) değerine göre)', 'Keskinleştirme filtresi', 'Kenar bulma filtresi', 'Ortalama (Mean) filtresi', 'Ortanca (Median) filtresi', 'Kontra Harmonik Ortalama filtresi'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Filtreleme Menüsü';
            app.NEXTButton = uibutton(app.FiltrelemeUIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.PREVButton = uibutton(app.FiltrelemeUIFigure, 'push');
            app.PREVButton.ButtonPushedFcn = createCallbackFcn(app, @PREVButtonPushed, true);
            app.PREVButton.Text = 'PREV';
            % Show the figure after all components are created
            app.FiltrelemeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiltrelemeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiltrelemeUIFigure)
        end
    end
end