classdef lastpage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GrntKaydetmeUIFigure  matlab.ui.Figure
        ResmiKaydetButton     matlab.ui.control.Button
        HangiFormattaKaydetmekIstersinizLabel  matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallerApp % Description
        img % Description
        fmt % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, img)
            app.CallerApp=caller;
            app.img=img;
            imshow(app.img,"Parent",app.UIAxes);
        end

        % Button pushed function: ResmiKaydetButton
        function ResmiKaydetButtonPushed(app, event)
            if isequal(app.fmt,"jpg")
                imwrite(app.img,'resim.jpg');
            elseif isequal(app.fmt,"bmp")
                imwrite(app.img,'resim.bmp');
            elseif isequal(app.fmt,"png")
                imwrite(app.img,'resim.png');
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.fmt = app.DropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GrntKaydetmeUIFigure = uifigure('Visible', 'off');
            app.GrntKaydetmeUIFigure.Name = 'Görüntü Kaydetme';
            app.UIAxes = uiaxes(app.GrntKaydetmeUIFigure);
            title(app.UIAxes, 'Görüntü')
            app.DropDown = uidropdown(app.GrntKaydetmeUIFigure);
            app.DropDown.Items = {'jpg', 'bmp', 'png'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'jpg';
            app.HangiFormattaKaydetmekIstersinizLabel = uilabel(app.GrntKaydetmeUIFigure);
            app.HangiFormattaKaydetmekIstersinizLabel.Text = {'Hangi Formatta Kaydetmek '; 'Istersiniz'};
            app.ResmiKaydetButton = uibutton(app.GrntKaydetmeUIFigure, 'push');
            app.ResmiKaydetButton.ButtonPushedFcn = createCallbackFcn(app, @ResmiKaydetButtonPushed, true);
            app.ResmiKaydetButton.Text = 'Resmi Kaydet';
            % Show the figure after all components are created
            app.GrntKaydetmeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lastpage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GrntKaydetmeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GrntKaydetmeUIFigure)
        end
    end
end