classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GrntOkumaUIFigure  matlab.ui.Figure
        NEXTButton         matlab.ui.control.Button
        OpenImageButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    properties (Access = private)
        preprocess1 % Description
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.jpeg;*.bmp;*.png'},'Select an image');
            filename=strcat(path,file);
            app.img=imread(filename);
            imshow(app.img,"Parent",app.UIAxes);
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.preprocess1=preProcessing(app, app.img);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GrntOkumaUIFigure = uifigure('Visible', 'off');
            app.GrntOkumaUIFigure.Name = 'Görüntü Okuma';
            app.UIAxes = uiaxes(app.GrntOkumaUIFigure);
            title(app.UIAxes, 'Opened Image')
            app.OpenImageButton = uibutton(app.GrntOkumaUIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.NEXTButton = uibutton(app.GrntOkumaUIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            % Show the figure after all components are created
            app.GrntOkumaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GrntOkumaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GrntOkumaUIFigure)
        end
    end
end