classdef morfolojik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MorfolojiklemUIFigure        matlab.ui.Figure
        PREVButton                   matlab.ui.control.Button
        NEXTButton                   matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        MorfolojiklemlerButtonGroup  matlab.ui.container.ButtonGroup
        MorfolojiklemUygulamakIstemiyorumButton  matlab.ui.control.RadioButton
        MorfolojiklemUygulamakIstiyorumButton  matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallerApp % Description
        img % Description
        proc_img % Description
        wb_img % Description
        lastpage % Descriptio
        filtr % Description
    end
    
    methods (Access = private)
        
        function wb_img = white_black(app,gray_img,t)
            [h,w]=size(gray_img);
            for i=1:h
                for j=1:w
                    if gray_img(i,j)<t
                        gray_img(i,j)=0;
                    else
                        gray_img(i,j)=1;
                    end
                end
            end
            disp(gray_img);
            wb_img=gray_img;
        end
        
        function dil_fig = dilation(app,fig)
            [h,w]=size(fig);
            disp(fig);
            dfig=zeros(h,w);
            for i=1:h
                for j=1:w
                    if fig(i,j)==1
                        dfig(i,j-1)=1;
                        dfig(i-1,j)=1;
                        dfig(i,j)=1;
                        dfig(i,j+1)=1;
                        dfig(i+1,j)=1;
                    end
                end
            end
            disp(dfig);
            dil_fig=dfig;
        end
        
        function ers_fig = erosion(app,fig)
            [h,w]=size(fig);
            disp(fig);
            efig=zeros(h,w);
            for i=2:h-1
                for j=2:w-1
                    if (fig(i,j)==1) && (fig(i,j-1)==1) && (fig(i-1,j)==1) && (fig(i,j+1)==1) && (fig(i+1,j)==1)
                        efig(i,j)=1;
                    end
                end
            end
            disp(efig);
            ers_fig=efig;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, img)
            app.CallerApp=caller;
            app.img=img;
            app.proc_img=img;
        end

        % Selection changed function: MorfolojiklemlerButtonGroup
        function MorfolojiklemlerButtonGroupSelectionChanged(app, event)
            selectedButton = app.MorfolojiklemlerButtonGroup.SelectedObject;
            if isequal(selectedButton.Text,'Morfolojik İşlem Uygulamak Istiyorum')
                app.DropDown.Visible="on";
                app.UIAxes.Visible="on";
                % wb_img
                [h,w,c]=size(app.img);
                if c==3
                    app.wb_img=img2gray(app.img);
                else
                    app.wb_img=app.img;
                end
                app.wb_img=app.white_black(app.wb_img,112);
                app.wb_img=mat2gray(app.wb_img);
                %imshow(app.wb_img);
                imshow(app.wb_img,"Parent",app.UIAxes);
            else
                app.DropDown.Visible="off";
                app.UIAxes.Visible="off";
            end    
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if isequal(value, "Siyah beyaz resimde genişletme")
                dil_img=padarray(app.wb_img,[1 1],0,"both");
                dil_img=app.dilation(dil_img);
                dil_img=mat2gray(dil_img);
                imshow(dil_img,"Parent",app.UIAxes);
                app.proc_img=dil_img;
            elseif isequal(value, "Siyah beyaz resimde erozyon")
                ers_img=app.erosion(app.wb_img);
                ers_img=mat2gray(ers_img);
                imshow(ers_img,"Parent",app.UIAxes);
                app.proc_img=ers_img;
            elseif isequal(value, "İskelet çıkartma (Skeletonization)")
                figure=padarray(app.wb_img,[1 1],0,"both");
                efig=app.erosion(figure);
                dfig=app.dilation(efig);
                ifig=figure-dfig;
                while 1
                    figure=efig;
                    efig=app.erosion(figure);
                    dfig=app.dilation(efig);
                    ifig=ifig+(figure-dfig);
                    if sum(efig)==0
                        break;
                    end
                end
                ifig=mat2gray(ifig);
                imshow(ifig,"Parent",app.UIAxes);
                app.proc_img=ifig;
                disp(ifig);
            end
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.lastpage=lastpage(app,app.proc_img);
            app.delete;
        end

        % Button pushed function: PREVButton
        function PREVButtonPushed(app, event)
            app.filtr=filtering(app,app.img);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MorfolojiklemUIFigure = uifigure('Visible', 'off');
            app.MorfolojiklemUIFigure.Name = 'Morfolojik İşlem';
            app.UIAxes = uiaxes(app.MorfolojiklemUIFigure);
            title(app.UIAxes, 'Morfolojik')
            app.UIAxes.Visible = 'off';
            app.MorfolojiklemlerButtonGroup = uibuttongroup(app.MorfolojiklemUIFigure);
            app.MorfolojiklemlerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MorfolojiklemlerButtonGroupSelectionChanged, true);
            app.MorfolojiklemlerButtonGroup.Title = 'Morfolojik İşlemler';
            app.MorfolojiklemUygulamakIstiyorumButton = uiradiobutton(app.MorfolojiklemlerButtonGroup);
            app.MorfolojiklemUygulamakIstiyorumButton.Text = 'Morfolojik İşlem Uygulamak Istiyorum';
            app.MorfolojiklemUygulamakIstemiyorumButton = uiradiobutton(app.MorfolojiklemlerButtonGroup);
            app.MorfolojiklemUygulamakIstemiyorumButton.Text = 'Morfolojik İşlem Uygulamak Istemiyorum';
            app.MorfolojiklemUygulamakIstemiyorumButton.Value = true;
            app.DropDown = uidropdown(app.MorfolojiklemUIFigure);
            app.DropDown.Items = {'Morfolojik İşlemler', 'Siyah beyaz resimde genişletme', 'Siyah beyaz resimde erozyon', 'İskelet çıkartma (Skeletonization)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Morfolojik İşlemler';
            app.NEXTButton = uibutton(app.MorfolojiklemUIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.PREVButton = uibutton(app.MorfolojiklemUIFigure, 'push');
            app.PREVButton.ButtonPushedFcn = createCallbackFcn(app, @PREVButtonPushed, true);
            app.PREVButton.Text = 'PREV';
            % Show the figure after all components are created
            app.MorfolojiklemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morfolojik(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MorfolojiklemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MorfolojiklemUIFigure)
        end
    end
end