classdef preProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nileme1UIFigure     matlab.ui.Figure
        PREVButton          matlab.ui.control.Button
        NEXTButton          matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        OnIslemButtonGroup  matlab.ui.container.ButtonGroup
        OnIslemUygulamakIstemiyorumButton  matlab.ui.control.RadioButton
        OnIslemUygulamakIstiyorumButton  matlab.ui.control.RadioButton
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        img % Description
        preprocess2 % Description
        proc_img % Description
    end
    
    
    methods (Access = private)
        
        function wb_img = white_black(app,gray_img,t)
            [h,w]=size(gray_img);
            for i=1:h
                for j=1:w
                    if gray_img(i,j)<t
                        gray_img(i,j)=0;
                    else
                        gray_img(i,j)=1;
                    end
                end
            end
            wb_img=gray_img;
        end
        
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, img)
            app.CallingApp=caller;
            app.img=img;
            app.proc_img=app.img;
        end

        % Selection changed function: OnIslemButtonGroup
        function OnIslemButtonGroupSelectionChanged(app, event)
            selectedButton = app.OnIslemButtonGroup.SelectedObject;
            if isequal(selectedButton.Text,'On Islem Uygulamak Istiyorum')
                app.DropDown.Visible="on";
                app.UIAxes.Visible="on";
                imshow(app.img,"Parent",app.UIAxes);
            else
                app.DropDown.Visible="off";
                app.UIAxes.Visible="off";
            end    
        end

        % Callback function
        function ButtonPushed(app, event)
            app.Label.Text=app.img;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if isequal(value,"Renkli resmi > Gri seviye resme dönüştürme")
                gray_img=im2gray(app.img);
                imshow(gray_img,"Parent",app.UIAxes);
                app.proc_img=gray_img;
            elseif isequal(value,"Gri resmi > Siyah Beyaz resme dönüştürme(Eşik girerek)")
                [h,w,c]=size(app.img);
                if c==3
                    wb_img=im2gray(app.img);
                else
                    wb_img=app.img;
                end
                t=112;
                wb_img=app.white_black(wb_img,t);
                wb_img=mat2gray(wb_img);
                imshow(wb_img,"Parent",app.UIAxes);
                app.proc_img=wb_img;
            elseif isequal(value, "Zoom in – Zoom out")
                [h,w]=size(app.img);
                for i=1:2*h
                    for j=1:2*w
                        n=i-floor(i/2);
                        m=j-floor(j/2);
                        zoom_in(i,j)=app.img(n,m);
                    end
                end
                for i=1:h/2
                    for j=1:w/2
                        n=i*2;
                        m=j*2;
                        zoom_out(i,j)=app.img(n,m);
                    end
                end
                imshow(zoom_out);
                %imshow(zoom_in);
            end
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed2(app, event)
            app.preprocess2=preProcessing2(app, app.proc_img);
            app.delete;
        end

        % Button pushed function: PREVButton
        function PREVButtonPushed(app, event)
            mainApp;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nileme1UIFigure = uifigure('Visible', 'off');
            app.nileme1UIFigure.Name = 'Önişleme-1';
            app.UIAxes = uiaxes(app.nileme1UIFigure);
            title(app.UIAxes, 'Onislem Sonucu')
            app.UIAxes.Visible = 'off';
            app.OnIslemButtonGroup = uibuttongroup(app.nileme1UIFigure);
            app.OnIslemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OnIslemButtonGroupSelectionChanged, true);
            app.OnIslemButtonGroup.Title = 'On Islem';
            app.OnIslemUygulamakIstiyorumButton = uiradiobutton(app.OnIslemButtonGroup);
            app.OnIslemUygulamakIstiyorumButton.Text = 'On Islem Uygulamak Istiyorum';
            app.OnIslemUygulamakIstemiyorumButton = uiradiobutton(app.OnIslemButtonGroup);
            app.OnIslemUygulamakIstemiyorumButton.Text = 'On Islem Uygulamak Istemiyorum';
            app.OnIslemUygulamakIstemiyorumButton.Value = true;
            app.DropDown = uidropdown(app.nileme1UIFigure);
            app.DropDown.Items = {'Onislem Menusu-1', 'Renkli resmi > Gri seviye resme dönüştürme', 'Gri resmi > Siyah Beyaz resme dönüştürme(Eşik girerek)', 'Zoom in – Zoom out', 'Resimden istenilen bölgenin kesilip alınması'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Onislem Menusu-1';
            app.NEXTButton = uibutton(app.nileme1UIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed2, true);
            app.NEXTButton.Text = 'NEXT';
            app.PREVButton = uibutton(app.nileme1UIFigure, 'push');
            app.PREVButton.ButtonPushedFcn = createCallbackFcn(app, @PREVButtonPushed, true);
            app.PREVButton.Text = 'PREV';
            % Show the figure after all components are created
            app.nileme1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preProcessing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nileme1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nileme1UIFigure)
        end
    end
end