classdef preProcessing2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nileme2UIFigure      matlab.ui.Figure
        PREVButton           matlab.ui.control.Button
        NEXTButton           matlab.ui.control.Button
        DropDown             matlab.ui.control.DropDown
        Onislem2ButtonGroup  matlab.ui.container.ButtonGroup
        OnIslemUygulamakIstemiyorumButton  matlab.ui.control.RadioButton
        OnIslemUygulamakIstiyorumButton  matlab.ui.control.RadioButton
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallerApp % Description
        img % Description
        filtering % Description
        proc_img % Description
        preProc1 % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, img)
            app.CallerApp=caller;
            app.img=img;
            app.proc_img=img;
        end

        % Selection changed function: Onislem2ButtonGroup
        function Onislem2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Onislem2ButtonGroup.SelectedObject;
            if isequal(selectedButton.Text,'On Islem Uygulamak Istiyorum')
                app.DropDown.Visible="on";
                app.UIAxes.Visible="on";
            else
                app.DropDown.Visible="off";
                app.UIAxes.Visible="off";
            end    
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if isequal(value,"Histogram oluşturma")
                c=0;
                [h,w,c]=size(app.img);
                if c==3
                    app.UIAxes2.Visible="on";
                    app.UIAxes3.Visible="on";
                    histr = histogram(app.UIAxes, app.img(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.UIAxes2, app.img(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.UIAxes3, app.img(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                else
                    histr = histogram(app.UIAxes, app.img, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                end
            elseif isequal(value,"Histogram Eşitleme (Equalization - Gri seviye resimde)")
                histec=histeq(app.img);
                app.proc_img=histec;
                %histr = histogram(app.UIAxes, histec, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                [h,w]=size(app.img);
                pixel_sum=h*w;
                [pcount,~]=imhist(app.img);
                glevel=pcount;
                for i=2:length(glevel)
                    glevel(i)=glevel(i-1)+glevel(i);
                end
                for i=1:length(glevel)
                    glevel(i)=int16((glevel(i)/pixel_sum)*255);
                end

                % tekrar eden pixel level sayisini bir yapma
                disp("tekrar sonrasi")
                i=1;
                n_glevel(i)=glevel(1);
                n_pcount(i)=pcount(1);
                for j=2:length(glevel)
                    if isequal(n_glevel(i),glevel(j))
                        n_pcount(i)=n_pcount(i)+pcount(j);
                    else
                        i=i+1;
                        n_glevel(i)=glevel(j);
                        n_pcount(i)=pcount(j);
                    end
                end
                plot(app.UIAxes,n_glevel,n_pcount,"Color",[0 0 1]);

            elseif isequal(value,"Görüntü Nicemleme (Quantization – Ton Sayısı Seçilerek)")
                app.UIAxes2.Visible="on";
                app.UIAxes3.Visible="off";
                [h,w,c]=size(app.img);
                if c==3
                    threshRGB = multithresh(app.img,7);
                    threshForPlanes = zeros(3,7);			
                    for i = 1:3
                        threshForPlanes(i,:) = multithresh(app.img(:,:,i),7);
                    end
                    value = [0 threshRGB(2:end) 255];
                    quantRGB = imquantize(app.img, threshRGB, value);
                    quantPlane = zeros( size(app.img) );
                    for i = 1:3
                        value = [0 threshForPlanes(i,2:end) 255];
                        quantPlane(:,:,i) = imquantize(app.img(:,:,i),threshForPlanes(i,:),value);
                    end
                    quantPlane = uint8(quantPlane);
                    imshow(quantRGB,"Parent",app.UIAxes);
                    imshow(quantPlane,"Parent",app.UIAxes2);
                    app.proc_img=quantRGB;
                else
                    thresh = multithresh(app.img,7);
                    valuesMax = [thresh max(app.img(:))];
                    [quant8_img_max, index] = imquantize(app.img,thresh,valuesMax);
                    imshow(quant8_img_max,"Parent",app.UIAxes);
                    valuesMin = [thresh min(app.img(:))];
                    [quant8_img_min, index] = imquantize(app.img,thresh,valuesMin);
                    imshow(quant8_img_min,"Parent",app.UIAxes2);
                    app.proc_img=quant8_img_max;
                end
            end
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.filtering=filtering(app,app.proc_img);
            app.delete;
        end

        % Button pushed function: PREVButton
        function PREVButtonPushed(app, event)
             app.preProc1=preProcessing(app,app.img);
             app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nileme2UIFigure = uifigure('Visible', 'off');
            app.nileme2UIFigure.Name = 'Önişleme-2';
            app.UIAxes = uiaxes(app.nileme2UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.nileme2UIFigure);
            title(app.UIAxes2, 'Histogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.nileme2UIFigure);
            title(app.UIAxes3, 'Histogram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.Onislem2ButtonGroup = uibuttongroup(app.nileme2UIFigure);
            app.Onislem2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Onislem2ButtonGroupSelectionChanged, true);
            app.Onislem2ButtonGroup.Title = 'Onislem-2';
            app.OnIslemUygulamakIstiyorumButton = uiradiobutton(app.Onislem2ButtonGroup);
            app.OnIslemUygulamakIstiyorumButton.Text = 'On Islem Uygulamak Istiyorum';
            app.OnIslemUygulamakIstemiyorumButton = uiradiobutton(app.Onislem2ButtonGroup);
            app.OnIslemUygulamakIstemiyorumButton.Text = 'On Islem Uygulamak Istemiyorum';
            app.OnIslemUygulamakIstemiyorumButton.Value = true;
            app.DropDown = uidropdown(app.nileme2UIFigure);
            app.DropDown.Items = {'Onislem Menusu-2', 'Histogram oluşturma', 'Histogram Eşitleme (Equalization - Gri seviye resimde)', 'Görüntü Nicemleme (Quantization – Ton Sayısı Seçilerek)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Onislem Menusu-2';
            app.NEXTButton = uibutton(app.nileme2UIFigure, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.PREVButton = uibutton(app.nileme2UIFigure, 'push');
            app.PREVButton.ButtonPushedFcn = createCallbackFcn(app, @PREVButtonPushed, true);
            app.PREVButton.Text = 'PREV';
            % Show the figure after all components are created
            app.nileme2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preProcessing2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nileme2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nileme2UIFigure)
        end
    end
end